classdef gui_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout3                    matlab.ui.container.GridLayout
        OpenButton                     matlab.ui.control.Button
        GridLayout2                    matlab.ui.container.GridLayout
        CurrentPositionEditFieldLabel  matlab.ui.control.Label
        CurrentPositionEditField       matlab.ui.control.EditField
        Button                         matlab.ui.control.StateButton
        UIAxes                         matlab.ui.control.UIAxes
    end

    properties (Access = private)
        movePlot_flag = 0; % documentation wether the mouse is clicked and meanwhile moving
        InputImage % documentation of the Image
        OuterRect
        InnerRect
        VanishPoint
    end
    % specify VanishPoint and RadialLines (function)
    methods (Access = private)
        
        % check if mouse is within axes
        function check_mouse_within_axes(app)
            if app.VanishPoint(1) < 0 % x-axis
                app.VanishPoint(1) = 0;
            elseif app.VanishPoint(1) > size(app.InputImage,2)
                app.VanishPoint(1) = size(app.InputImage,2);
            end
            if app.VanishPoint(2) < 0 % y-axis
                app.VanishPoint(2) = 0;
            elseif app.VanishPoint(2) > size(app.InputImage,1)
                app.VanishPoint(2) = size(app.InputImage,1);
            end
        end
        
        % draw the initial vanishpoint and radiallines
        function specify_VanishPoint_RadialLines(app)
            hold(app.UIAxes, 'on');
            app.check_mouse_within_axes();
            plot(app.UIAxes, app.VanishPoint(1), app.VanishPoint(2), '*', 'color', 'blue');
            hold(app.UIAxes, 'off');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.UIAxes,'off');
            title(app.UIAxes,'');
            xlabel(app.UIAxes,'');
            ylabel(app.UIAxes,'');
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            [file,path,FilterIndex] = uigetfile('*.*','Select an Image File');
            if FilterIndex == 0
                return;
            end
            app.InputImage = imread(fullfile(path,file));
            imshow(app.InputImage,'Parent',app.UIAxes);
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            tf = strcmp(app.UIFigure.SelectionType, 'normal');
            if tf == 1 % left click of the mouse
                if app.movePlot_flag == 0
                    app.VanishPoint = app.UIAxes.CurrentPoint(1,1:2);
                    % specify VanishPoint and RadialLines (function)
                    app.specify_VanishPoint_RadialLines();
                else

                end
            else            
            end 
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            if app.movePlot_flag == 1    
                % Determine if mouse is within image
                cp = app.UIAxes.CurrentPoint(1,1:2);
                isInAxes = cp(1) >= 0 && ...
                        cp(1) <= size(app.InputImage,2) && ...
                        cp(2) >= 0 && ...
                        cp(2) <= size(app.InputImage,1);
                if isInAxes
                    % Update displayed coordinates
                    set(app.CurrentPositionEditField, 'Value',...
                        sprintf('%.2f,  %.2f', cp));
                else
                     % Clear displayed coordinates
                    set(app.CurrentPositionEditField, 'Value', '');
                end
            end
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            
        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            value = app.Button.Value;
            if value == 1
                app.movePlot_flag = 1;
            else
                app.movePlot_flag = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.Button = uibutton(app.GridLayout2, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = 'Button';
            app.CurrentPositionEditField = uieditfield(app.GridLayout2, 'text');
            app.CurrentPositionEditFieldLabel = uilabel(app.GridLayout2);
            app.CurrentPositionEditFieldLabel.Text = 'CurrentPosition';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.OpenButton = uibutton(app.GridLayout3, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end